classdef ProjetoVFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        lancar             matlab.ui.control.Button
        angulo             matlab.ui.control.Slider
        nguloSliderLabel   matlab.ui.control.Label
        nivel              matlab.ui.control.DropDown
        NvelDropDownLabel  matlab.ui.control.Label
        planeta            matlab.ui.control.DropDown
        AstroLabel         matlab.ui.control.Label
        velocidade         matlab.ui.control.NumericEditField
        VelocidadeInicialEditFieldLabel  matlab.ui.control.Label
        inicio             matlab.ui.control.Button
        grafico            matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
        alvo4 
        yalvo4
        v_alvo
        alvo5
        yalvo5
        ycheck1
        ycheck2
        check1
        check2
        parede1
        parede2
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: inicio
        function inicioButtonPushed(app, event)
            ang = app.angulo.Value;
            vel= app.velocidade.Value;

            switch app.planeta.Value
                case 'Terra'
                    a=9.8;
                    c='g';
                case 'Marte'
                    a=3.7;
                    c='r';
                case 'Júpiter'
                    a=24.8;
                    c='y';
                case 'Plutão'
                    a=0.6;
                    c='k';
                otherwise
                    a=1.6;
                    c='w';
            end

            vx = vel*cosd(ang);
            vy = vel*sind(ang);
            tvoo = vy/(a/2);
            yl = (vy^2)/(2*a);

            switch app.nivel.Value
                case 'Simulador'
                    yl = (vy^2)/(2*a);
                    xl = vx*tvoo;
                    xchao = 0:0.1:xl;
                    ychao = zeros(1,length(xchao));
                    plot(app.grafico,xchao,ychao,c)
                    xlim(app.grafico,[0 xl])
                    ylim(app.grafico,[0 yl])
                    
                case 'Nível 1'
                    x1=25;
                    y1=15;
                    alvo=[x1 , x1+1 , x1+1 , x1 , x1 ; y1 , y1 , y1+3 , y1+3 , y1];
                    fill(app.grafico,alvo(1,:),alvo(2,:),'r')
                    hold(app.grafico,'on')
                    xchao = 0:0.1:x1+2;
                    ychao = zeros(1,length(xchao));
                    plot(app.grafico,xchao,ychao,c)
                    xlim(app.grafico,[0 x1+2])
                    ylim(app.grafico,[0 y1+5])
                    
                case 'Nível 2'
                    xparede=15;
                    yparede=18;
                    xalvo=30;
                    yalvo=3;
                    alvo=[xalvo , xalvo+3 , xalvo+3 , xalvo , xalvo ; yalvo , yalvo , yalvo+1 , yalvo+1 , yalvo];
                    parede=[xparede , xparede+2 , xparede+2 , xparede , xparede ; 0 , 0 , yparede , yparede , 0];
                    fill(app.grafico,alvo(1,:),alvo(2,:),'r')
                    hold(app.grafico,'on')
                    fill(app.grafico,parede(1,:),parede(2,:),'k')
                    hold(app.grafico,'on')
                    xchao = 0:0.1:xalvo+5;
                    ychao = zeros(1,length(xchao));
                    plot(app.grafico,xchao,ychao,c)
                    xlim(app.grafico,[0 xalvo+5])
                    ylim(app.grafico,[0 32])

                case 'Nível 3' 
                    xalvo1=22;
                    xalvo2=27;
                    xalvo3=32;
                    yalvo1=9.7;
                    yalvo2=8;
                    yalvo3=5;
                    alvo1=[xalvo1 , xalvo1+3 , xalvo1+3 , xalvo1 , xalvo1 ; yalvo1 , yalvo1 , yalvo1+1 , yalvo1+1 , yalvo1];
                    alvo2=[xalvo2 , xalvo2+3 , xalvo2+3 , xalvo2 , xalvo2 ; yalvo2 , yalvo2 , yalvo2+1 , yalvo2+1 , yalvo2];
                    alvo3=[xalvo3 , xalvo3+3 , xalvo3+3 , xalvo3 , xalvo3 ; yalvo3 , yalvo3 , yalvo3+1 , yalvo3+1 , yalvo3];
                    %mrot=[cosd(45) -sind(45);sind(45) cosd(45)];
                    % alvo11=mrot*alvo1;
                    % alvo22=mrot*alvo2;
                    % alvo33=mrot*alvo3;
                    fill(app.grafico,alvo1(1,:),alvo1(2,:),'r')
                    hold(app.grafico,'on')
                    fill(app.grafico,alvo2(1,:),alvo2(2,:),'r')
                    hold(app.grafico,'on')
                    fill(app.grafico,alvo3(1,:),alvo3(2,:),'r')
                    hold(app.grafico,'on')
                    xchao = 0:0.1:42;
                    ychao = zeros(1,length(xchao));
                    plot(app.grafico,xchao,ychao,c)
                    xlim(app.grafico,[0 42])
                    ylim(app.grafico,[0 15])
                case 'Nível 4'
                    app.yalvo4 = 10;
                    xalvo = 30;
                    app.v_alvo = 0.25;
                    while true
                        hold(app.grafico,'off')
                        if app.yalvo4 == 25
                            app.v_alvo = -app.v_alvo;
                        elseif app.yalvo4 == 2
                            app.v_alvo = -app.v_alvo;
                        end
                        app.yalvo4 = app.yalvo4+app.v_alvo;
                        app.alvo4=[xalvo , xalvo+1 , xalvo+1 , xalvo , xalvo ; app.yalvo4 , app.yalvo4 , app.yalvo4+5 , app.yalvo4+5 , app.yalvo4];
                        fill(app.grafico,app.alvo4(1,:),app.alvo4(2,:),'r')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:xalvo+5;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 xalvo+5])
                        ylim(app.grafico,[0 32])
                        %drawnow
                        pause(0.05)
                    end

                otherwise 
                    xparede1 = 20;
                    yparede1 = 7;
                    xparede2 = 20;
                    yparede2 = 12;
                    app.parede1=[xparede1 , xparede1+2 , xparede1+2 , xparede1 , xparede1 ; 0 , 0 , yparede1 , yparede1 , 0];
                    app.parede2=[xparede2 , xparede2+2 , xparede2+2 , xparede2 , xparede2 ; yparede2 , yparede2 , 32 , 32 , yparede2];
                    app.yalvo5 = 13;
                    xalvo = 40;
                    app.ycheck1 = 13;
                    xcheck1 = 34;
                    app.ycheck2 = 13;
                    xcheck2 = 37;
                    app.v_alvo = 0.25;
                    while true
                        hold(app.grafico,'off')
                        hold(app.grafico,'off')
                        if app.yalvo5 == 25
                            app.v_alvo = -app.v_alvo;
                        elseif app.yalvo5 == 2
                            app.v_alvo = -app.v_alvo;
                        end
                        app.yalvo5 = app.yalvo5+app.v_alvo;
                        app.ycheck1 = app.ycheck1+app.v_alvo;
                        app.ycheck2 = app.ycheck2-app.v_alvo;
                        app.alvo5=[xalvo , xalvo+1 , xalvo+1 , xalvo , xalvo ; app.yalvo5 , app.yalvo5 , app.yalvo5+5 , app.yalvo5+5 , app.yalvo5];
                        app.check1 = [xcheck1 xcheck1+1 xcheck1+1 xcheck1 xcheck1 ; app.ycheck1 app.ycheck1 app.ycheck1+5 app.ycheck1+5 app.ycheck1];
                        app.check2 = [xcheck2 xcheck2+1 xcheck2+1 xcheck2 xcheck2 ; app.ycheck2 app.ycheck2 app.ycheck2+5 app.ycheck2+5 app.ycheck2];
                        fill(app.grafico,app.parede1(1,:),app.parede1(2,:),'k')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.parede2(1,:),app.parede2(2,:),'k')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.alvo5(1,:),app.alvo5(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.check1(1,:),app.check1(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.check2(1,:),app.check2(2,:),'r')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:xalvo+5;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 xalvo+5])
                        ylim(app.grafico,[0 32])
                        %drawnow
                        pause(0.05)
                    end
            end


        end

        % Button pushed function: lancar
        function lancarButtonPushed(app, event)
            ang = app.angulo.Value;
            vel= app.velocidade.Value;

            switch app.planeta.Value
                case 'Terra'
                    a=9.8;
                    c='g';
                case 'Marte'
                    a=3.7;
                    c='r';
                case 'Júpiter'
                    a=24.8;
                    c='y';
                case 'Plutão'
                    a=0.6;
                    c='k';
                otherwise
                    a=1.6;
                    c='w';
            end
            
            vx = vel*cosd(ang);
            vy = vel*sind(ang);
            tvoo = vy/(a/2);
            yl = (vy^2)/(2*a);
            xl = vx*tvoo;

            switch app.nivel.Value
                case 'Simulador'
                    for t = 0:0.01:tvoo
                        xchao = 0:0.1:xl;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        hold(app.grafico,'on')
                        xlim(app.grafico,[0 xl])
                        ylim(app.grafico,[0 yl])
                        x = vx*t;
                        y = vy*t - (a/2)*t.^2;
                        vy2 = vy -10*t;
                        m = sqrt(vx^2+vy2^2);
                        vxu = 3*(vx/m);
                        vy2u = 3*(vy2/m);
                        quiver(app.grafico,x,y,vxu,vy2u,'m')
                        plot(app.grafico,x,y,'bo','Markersize',10,'MarkerFaceColor','b')
                        hold(app.grafico,'on')
                        pause(0.05)
                        hold(app.grafico,'off')
                    end
                    
                case 'Nível 1'
                    x1=25;
                    y1=15;
                    x=0;
                    t=0;
                    win=1;

                    while t<tvoo
                        alvo=[x1 , x1+1 , x1+1 , x1 , x1 ; y1 , y1 , y1+3 , y1+3 , y1];
                        fill(app.grafico,alvo(1,:),alvo(2,:),'r')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:x1+2;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 x1+2])
                        ylim(app.grafico,[0 y1+5])
                        x = vx*t;
                        y = vy*t - (a/2)*t.^2;
                        vy2 = vy -10*t;
                        m = sqrt(vx^2+vy2^2);
                        vxu = 3*(vx/m);
                        vy2u = 3*(vy2/m);
                        quiver(app.grafico,x,y,vxu,vy2u,'m')
                        plot(app.grafico,x,y,'bo','Markersize',10,'MarkerFaceColor','b')
                        hold(app.grafico,'on')
                        pause(0.01)
                        hold(app.grafico,'off')
                        t=t+0.01;
                        if (x>=25 && x<=26) && (y>=15 && y<=18)
                            filename = "youwon.png";
                            imshow(filename)
                            t=tvoo;
                            win=0;
                        end
                        if x>(x1+2)
                            t=tvoo;
                        end

                    end
                    
                    if win==1
                        filename = "youlost.png";
                        imshow(filename)
                    end

                case 'Nível 2'
                    xparede=15;
                    yparede=18;
                    xalvo=30;
                    yalvo=3;
                    x=0;
                    t=0;
                    win=1;
           
                    while t<tvoo
                        alvo=[xalvo , xalvo+3 , xalvo+3 , xalvo , xalvo ; yalvo , yalvo , yalvo+1 , yalvo+1 , yalvo];
                        parede=[xparede , xparede+2 , xparede+2 , xparede , xparede ; 0 , 0 , yparede , yparede , 0];
                        fill(app.grafico,alvo(1,:),alvo(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,parede(1,:),parede(2,:),'k')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:xalvo+5;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 xalvo+5])
                        ylim(app.grafico,[0 32])
                        x = vx*t;
                        y = vy*t - (a/2)*t.^2;
                        vy2 = vy -10*t;
                        m = sqrt(vx^2+vy2^2);
                        vxu = 3*(vx/m);
                        vy2u = 3*(vy2/m);
                        quiver(app.grafico,x,y,vxu,vy2u,'m')
                        plot(app.grafico,x,y,'bo','Markersize',10,'MarkerFaceColor','b')
                        hold(app.grafico,'on')
                        pause(0.01)
                        hold(app.grafico,'off')
                        t=t+0.01;
                        if (x>=30 && x<=33) && (y>=3 && y<=4)
                            filename = "youwon.png";
                            imshow(filename)
                            t=tvoo;
                            win=0;
                        end
                        if (x>=15 && x<=17) && (y>=0 && y<=18)
                            t=tvoo;
                            win=1;
                        end
                        if x>(xalvo+5)
                            t=tvoo;
                        end
                    end


                    if win==1
                        filename = "youlost.png";
                        imshow(filename)
                    end


                case 'Nível 3'
                    xalvo1=22;
                    xalvo2=27;
                    xalvo3=32;
                    yalvo1=9.7;
                    yalvo2=8;
                    yalvo3=5;
                    x=0;
                    t=0;
                    s1=0;
                    s2=0;
                    win=1;
                    while t<tvoo
                        alvo1=[xalvo1 , xalvo1+3 , xalvo1+3 , xalvo1 , xalvo1 ; yalvo1 , yalvo1 , yalvo1+1 , yalvo1+1 , yalvo1];
                        alvo2=[xalvo2 , xalvo2+3 , xalvo2+3 , xalvo2 , xalvo2 ; yalvo2 , yalvo2 , yalvo2+1 , yalvo2+1 , yalvo2];
                        alvo3=[xalvo3 , xalvo3+3 , xalvo3+3 , xalvo3 , xalvo3 ; yalvo3 , yalvo3 , yalvo3+1 , yalvo3+1 , yalvo3];
                        fill(app.grafico,alvo1(1,:),alvo1(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,alvo2(1,:),alvo2(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,alvo3(1,:),alvo3(2,:),'r')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:42;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 42])
                        ylim(app.grafico,[0 15])
                        x = vx*t;
                        y = vy*t - (a/2)*t.^2;
                        vy2 = vy -10*t;
                        m = sqrt(vx^2+vy2^2);
                        vxu = 3*(vx/m);
                        vy2u = 3*(vy2/m);
                        quiver(app.grafico,x,y,vxu,vy2u,'m')
                        plot(app.grafico,x,y,'bo','Markersize',10,'MarkerFaceColor','b')
                        hold(app.grafico,'on')
                        pause(0.01)
                        hold(app.grafico,'off')
                        t=t+0.01;
                        if (x>=22 && x<=25) && (y>=9.7 && y<=10.7)
                            s1=s1+1;

                        end
                        if (x>=27 && x<=30) && (y>=8 && y<=9)
                            s2=s2+1;
                                
                        end
                        if (x>=32 && x<=35) && (y>=5 && y<=6) && (s1>=1) && (s2>=1)

                            win=0;
                            filename = "youwon.png";
                            imshow(filename)
                            t=tvoo;

                        end
                        if x>(42)
                            t=tvoo;
                        end
                    end
                    if win==1
                        filename = "youlost.png";
                        imshow(filename)
                    end
                    

                case 'Nível 4'
                    t = 0;
                    win = 1;
                    while t<tvoo
                        if app.yalvo4 == 25
                            app.v_alvo = -app.v_alvo;
                        elseif app.yalvo4 == 2
                            app.v_alvo = -app.v_alvo;
                        end
                        app.yalvo4 = app.yalvo4+app.v_alvo;
                        app.alvo4=[30 , 31 , 31 , 30 , 30 ; app.yalvo4 , app.yalvo4 , app.yalvo4+5 , app.yalvo4+5 , app.yalvo4];
                        fill(app.grafico,app.alvo4(1,:),app.alvo4(2,:),'r')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:35;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 35])
                        ylim(app.grafico,[0 32]) 
                        x = vx*t;
                        y = vy*t - (a/2)*t.^2;
                        vy2 = vy -10*t;
                        m = sqrt(vx^2+vy2^2);
                        vxu = 3*(vx/m);
                        vy2u = 3*(vy2/m);
                        quiver(app.grafico,x,y,vxu,vy2u,'m')
                        plot(app.grafico,x,y,'bo','Markersize',10,'MarkerFaceColor','b')
                        hold(app.grafico,'on')
                        %drawnow
                        pause(0.0005)
                        hold(app.grafico,'off')
                        t=t+0.01;
                        if (x>=30 && x<=31) && (y>=app.yalvo4 && y<=(app.yalvo4)+5)
                            filename = "youwon.png";
                            imshow(filename)
                            t=tvoo;
                            win=0;
                        end
                        if x>(37)
                            t=tvoo;
                        end

                    end

                    if win==1
                        filename = "youlost.png";
                            imshow(filename)
                    end        
                otherwise
                    x=0;
                    t=0;
                    s1=0;
                    s2=0;
                    win=1;
                    xalvo = 40;
                    while t<tvoo
                        if app.yalvo5 == 25
                            app.v_alvo = -app.v_alvo;
                        elseif app.yalvo5 == 2
                            app.v_alvo = -app.v_alvo;
                        end
                        app.yalvo5 = app.yalvo5+app.v_alvo;
                        app.ycheck1 = app.ycheck1+app.v_alvo;
                        app.ycheck2 = app.ycheck2-app.v_alvo;
                        app.alvo5=[40 , 41 , 41 , 40 , 40 ; app.yalvo5 , app.yalvo5 , app.yalvo5+5 , app.yalvo5+5 , app.yalvo5];
                        app.check1=[34 35 35 34 34 ; app.ycheck1 app.ycheck1 app.ycheck1+5 app.ycheck1+5 app.ycheck1];
                        app.check2=[37 38 38 37 37 ; app.ycheck2 app.ycheck2 app.ycheck2+5 app.ycheck2+5 app.ycheck2];
                        fill(app.grafico,app.parede1(1,:),app.parede1(2,:),'k')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.parede2(1,:),app.parede2(2,:),'k')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.alvo5(1,:),app.alvo5(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.check2(1,:),app.check2(2,:),'r')
                        hold(app.grafico,'on')
                        fill(app.grafico,app.check1(1,:),app.check1(2,:),'r')
                        hold(app.grafico,'on')
                        xchao = 0:0.1:xalvo;
                        ychao = zeros(1,length(xchao));
                        plot(app.grafico,xchao,ychao,c)
                        xlim(app.grafico,[0 xalvo+5])
                        ylim(app.grafico,[0 32])
                        x = vx*t;
                        y = vy*t - (a/2)*t.^2;
                        vy2 = vy -10*t;
                        m = sqrt(vx^2+vy2^2);
                        vxu = 3*(vx/m);
                        vy2u = 3*(vy2/m);
                        quiver(app.grafico,x,y,vxu,vy2u,'m')
                        plot(app.grafico,x,y,'bo','Markersize',10,'MarkerFaceColor','b')
                        hold(app.grafico,'on')
                        pause(0.05)
                        hold(app.grafico,'off')
                        t=t+0.01;
                        if (x>=20 && x<=22) && ((y>=0 && y<=7) | (y>=12))
                            t=tvoo;
                            win=1;
                        end
                        if (x>=34 && x<=35) && (y>=app.ycheck1 && y<=app.ycheck1+5)
                            s1=s1+1;

                        end
                        if (x>=37 && x<=38) && (y>=app.ycheck2 && y<=app.ycheck2+5)
                            s2=s2+1;
                                
                        end
                        if (x>=40 && x<=41) && (y>=app.yalvo5 && y<=app.yalvo5+5)
                            if (s1>=1) && (s2>=1)
                                win=0;
                                filename = "youwon.png";
                                imshow(filename)
                            end                           
                            t=tvoo;

                        end
                        if x>(45)
                            t=tvoo;
                        end
                    end
                    if win==1
                        filename = "youlost.png";
                        imshow(filename)
                    end
                    
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Lançamento do projétil')
            xlabel(app.grafico, 'X')
            ylabel(app.grafico, 'Y')
            zlabel(app.grafico, 'Z')
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioButtonPushed, true);
            app.inicio.Text = 'Início';
            app.VelocidadeInicialEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadeInicialEditFieldLabel.Text = 'Velocidade Inicial';
            app.velocidade = uieditfield(app.UIFigure, 'numeric');
            app.velocidade.Limits = [0 300000000];
            app.velocidade.Value = 10;
            app.AstroLabel = uilabel(app.UIFigure);
            app.AstroLabel.Text = 'Astro';
            app.planeta = uidropdown(app.UIFigure);
            app.planeta.Items = {'Terra', 'Marte', 'Júpiter', 'Plutão', 'Lua'};
            app.planeta.Value = 'Terra';
            app.NvelDropDownLabel = uilabel(app.UIFigure);
            app.NvelDropDownLabel.Text = 'Nível';
            app.nivel = uidropdown(app.UIFigure);
            app.nivel.Items = {'Simulador', 'Nível 1', 'Nível 2', 'Nível 3', 'Nível 4', 'Nível 5'};
            app.nivel.Value = 'Nível 1';
            app.nguloSliderLabel = uilabel(app.UIFigure);
            app.nguloSliderLabel.Text = 'Ângulo';
            app.angulo = uislider(app.UIFigure);
            app.angulo.Limits = [0 90];
            app.angulo.Value = 30;
            app.lancar = uibutton(app.UIFigure, 'push');
            app.lancar.ButtonPushedFcn = createCallbackFcn(app, @lancarButtonPushed, true);
            app.lancar.Text = 'Lançar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjetoVFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end